classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        MaxyEditFieldLabel  matlab.ui.control.Label
        MaxyEditField       matlab.ui.control.NumericEditField
        MaxxEditFieldLabel  matlab.ui.control.Label
        MaxxEditField       matlab.ui.control.NumericEditField
        UITable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        % Description
        Path % Description
    end
    
    methods (Access = public)
        
        
        function results = updatePlot(app)
            for i=1:length(app.UITable.Data)
               line(app.UIAxes,[app.UITable.Data(i,1), app.UITable.Data(i,3)],[app.UITable.Data(i,2),app.UITable.Data(i,4)]) 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data=zeros(4,numel(app.UITable.ColumnName));
        end

        % Value changed function: MaxxEditField
        function MaxxEditFieldValueChanged(app, event)
            value = app.MaxxEditField.Value;
            app.UIAxes.XLim=[0 value];
        end

        % Value changed function: MaxyEditField
        function MaxyEditFieldValueChanged(app, event)
            value = app.MaxyEditField.Value;
            app.UIAxes.YLim=[0 value];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MaxyEditFieldLabel = uilabel(app.UIFigure);
            app.MaxyEditFieldLabel.Text = 'Max y';
            app.MaxyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxyEditField.ValueChangedFcn = createCallbackFcn(app, @MaxyEditFieldValueChanged, true);
            app.MaxxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxxEditFieldLabel.Text = 'Max x';
            app.MaxxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxxEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Start point X'; 'Start point Y'; 'End point X'; 'End point Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end